classdef Orb_GUI_v05_librevna < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        MesureS11vsfreqButton    matlab.ui.control.Button
        PolpatXOZButton          matlab.ui.control.Button
        Gauge                    matlab.ui.control.LinearGauge
        GaugeLabel               matlab.ui.control.Label
        RFPowerdBEditField       matlab.ui.control.NumericEditField
        RFPowerdBEditFieldLabel  matlab.ui.control.Label
        MaxEditField             matlab.ui.control.NumericEditField
        MaxEditFieldLabel        matlab.ui.control.Label
        IFBWKhzEditField         matlab.ui.control.NumericEditField
        IFBWKhzLabel             matlab.ui.control.Label
        freq_idEditField         matlab.ui.control.NumericEditField
        freq_idEditFieldLabel    matlab.ui.control.Label
        MeasurementsLabel        matlab.ui.control.Label
        ThetavsFreqButton        matlab.ui.control.Button
        PolarPatternButton       matlab.ui.control.Button
        Measurex0zButton         matlab.ui.control.Button
        MeasurePortButton        matlab.ui.control.Button
        SetasRefButton           matlab.ui.control.Button
        XOZandYOZButton          matlab.ui.control.Button
        MoveButton               matlab.ui.control.Button
        PortEditField            matlab.ui.control.NumericEditField
        PortEditFieldLabel       matlab.ui.control.Label
        PolDropDown              matlab.ui.control.DropDown
        PolDropDownLabel         matlab.ui.control.Label
        PortSpinner              matlab.ui.control.Spinner
        PortSpinnerLabel         matlab.ui.control.Label
        SparamDropDown           matlab.ui.control.DropDown
        SparamDropDownLabel      matlab.ui.control.Label
        FreqpointEditField       matlab.ui.control.NumericEditField
        FreqpointEditFieldLabel  matlab.ui.control.Label
        FreqStopEditField        matlab.ui.control.NumericEditField
        FreqStopEditFieldLabel   matlab.ui.control.Label
        FreqStartEditField       matlab.ui.control.NumericEditField
        FreqStartEditFieldLabel  matlab.ui.control.Label
        ThetaDropDown            matlab.ui.control.DropDown
        ThetaDropDownLabel       matlab.ui.control.Label
        PhiDropDown              matlab.ui.control.DropDown
        PhiDropDownLabel         matlab.ui.control.Label
        PolarDropDown            matlab.ui.control.DropDown
        PolarDropDownLabel       matlab.ui.control.Label
        AutoscaleButton          matlab.ui.control.Button
        MinEditField             matlab.ui.control.NumericEditField
        MinEditFieldLabel        matlab.ui.control.Label
        PlotPostprocessingLabel  matlab.ui.control.Label
        FreqEditField            matlab.ui.control.NumericEditField
        FreqEditFieldLabel       matlab.ui.control.Label
        PortMCUEditField         matlab.ui.control.EditField
        PortMCUEditFieldLabel    matlab.ui.control.Label
        PhiangleEditField        matlab.ui.control.NumericEditField
        PhiangleEditFieldLabel   matlab.ui.control.Label
        ThetaSampDropDown        matlab.ui.control.DropDown
        ThetaSampDropDownLabel   matlab.ui.control.Label
        PhiSampDropDown          matlab.ui.control.DropDown
        PhiSampDropDownLabel     matlab.ui.control.Label
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end


    properties (Access = public)


        % Etablissement de la connexion

        host = '127.0.0.1';
        port = 19542;

        % disp("Connexion SCPI établie");
        s = serialport("COM3", 115200);
        offset_angle = 0;
        delay_s = 0.05; 

        port_number = 72;


        %Measurement Paramaters
        sampling_phi = 20;
        sampling_theta=10;
        sampling_phi2 = 10;
        sampling_theta2=10;
        freq_id=1;
        freqs;

        Ephi;
        Etheta;
     
        FF_Ephi;
        FF_Etheta;

        %Calibration Paramaters
        Etheta_cal;
        Ephi_cal;
        cal_freq_start = 200;
        cal_freq_step=5;
        cal_freq_stop=2700;

        % Viewing Parameters
        max_amp=-20;
        min_amp=-80;

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          %  OPEN COM PORT for MCU control

            try
                % Define the COM port and baud rate
                comPort = app.PortMCUEditField.Value;
                baudRate = 115200;

                % Check if the serial port is already connected
                if ~isempty(instrfind('Port', comPort))
                    fclose(instrfind('Port', comPort));
                    delete(instrfind('Port', comPort));
                end

                % Create a serial port object
                app.s = serialport(comPort, baudRate);

                % Configure additional serial port properties
                configureTerminator(app.s, "CR/LF");  % Set terminator to carriage return/line feed
                app.s.Timeout = 10;                   % Set a timeout period of 10 seconds

                % Flush the serial port input buffer
                flush(app.s);

                % Display a message indicating that the serial port is open
                disp(['Serial port ' comPort ' is open and configured.']);
            end
        end

        % Button pushed function: MeasurePortButton
        function MeasurePortButtonPushed(app, event)


            % Lancement et lecture de S21
            host = '127.0.0.1';
            port = 19542;
            scpi = tcpclient(host, port, "Timeout", 30);
            
            % Measure first Theta polarization

            % Lancement et lecture de S21
            writeline(scpi, "VNA:TRACe:DATA? S21");
            raw= readline(scpi);
            pause(0.5);

            % Parse: Nettoyage des symboles '[]'et conversion en vecteur les données de S21
            raw = erase(raw, ["[", "]"]);
            nums = str2double(split(raw, ","));

            % Transformation du vecteur nums en matrice [freq, Re, Im]
            data = reshape(nums, 3, []).';
            freqs = data(:,1);
            S21_Re = data(:,2);
            S21_Im = data(:,3);
            S21_th = S21_Re + 1i*S21_Im;

            S21_tdb= 20*log10(abs(S21_th)); 

            % change to phi polarization
            value = num2str(app.PortEditField.Value+12);
            data2send=strcat('*',value ,'#');
            write(app.s, data2send, "char");
            pause(0.3);
            % Lancement et lecture de S21
            writeline(scpi, "VNA:TRACe:DATA? S21");
            raw= readline(scpi);
            pause(0.5);

            % Parse: Nettoyage des symboles '[]'et conversion en vecteur les données de S21
            raw = erase(raw, ["[", "]"]);
            nums = str2double(split(raw, ","));

            % Transformation du vecteur nums en matrice [freq, Re, Im]
            data = reshape(nums, 3, []).';
            freqs = data(:,1);
            S21_Re = data(:,2);
            S21_Im = data(:,3);
            S21_phi = S21_Re + 1i*S21_Im;

            S21_fdb= 20*log10(abs(S21_phi));

            plot(app.UIAxes,freqs, S21_tdb,'b'); 
            plot(app.UIAxes2, freqs, S21_fdb,'r');

            % plot(app.UIAxes2,freqs,angle(S21_tdb),'b') %
            % hold(app.UIAxes2);
            % plot(app.UIAxes2,freqs,angle(S21_fdb), 'r') %


        end

        % Button pushed function: MesureS11vsfreqButton
        function MesureS11vsfreqButtonPushed(app, event)
            % Lancement et lecture de S21
            host = '127.0.0.1';
            port = 19542;
            scpi = tcpclient(host, port, "Timeout", 30);

            % récupération des valeurs du GUI
            fstart = app.FreqStartEditField.Value * 1e6;
            fstop  = app.FreqStopEditField.Value * 1e6;
            points = app.FreqpointEditField.Value;
            IFBW   = app.IFBWKhzEditField.Value*1e3;
            RF_POWer = app.RFPowerdBEditField.Value;
            

            cmd1 = sprintf('VNA:FREQuency:STARt %d', round(fstart));
            cmd2 = sprintf('VNA:FREQuency:STOP %d', round(fstop));
            cmd3 = sprintf('VNA:ACQuisition:POINTS %d', round(points));
            cmd4 = sprintf('VNA:ACQuisition:IFBW %d', round(IFBW));
            cmd5 = sprintf('VNA:STIMulus:LVL %d', round(RF_POWer));
            disp (cmd1);
            disp (cmd2);
            disp (cmd3);
            disp (cmd4);
            disp (cmd5);
             
            
            writeline(scpi, string(cmd1));
            writeline(scpi, string(cmd2));
            writeline(scpi, string(cmd3));
            writeline(scpi, string(cmd4));
            writeline(scpi, string(cmd5));

            
            % Measure first Theta polarization

            % Lancement et lecture de S11
            writeline(scpi, "VNA:TRACe:DATA? S11");
            raw= readline(scpi);
            pause(0.5);

            % Parse: Nettoyage des symboles '[]'et conversion en vecteur les données de S11
            raw = erase(raw, ["[", "]"]);
            nums = str2double(split(raw, ","));

            % Transformation du vecteur nums en matrice [freq, Re, Im]
            data = reshape(nums, 3, []).';
            freqs = data(:,1);
            S11_Re = data(:,2);
            S11_Im = data(:,3);
            S11_th = S11_Re + 1i*S11_Im;

            S11_tdb= 20*log10(abs(S11_th)); 

             plot(app.UIAxes,freqs, S11_tdb,'b'); 
        end

        % Button pushed function: SetasRefButton
        function SetasRefButtonPushed(app, event)
            app.offset_angle = app.PhiangleEditField.Value+app.offset_angle;
            app.PhiangleEditField.Value=0;
        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
            angle = num2str(app.PhiangleEditField.Value+app.offset_angle);
            data2send=strcat('M',angle ,'D', newline);
            write(app.s, data2send, "char");
            disp(data2send)
        end

        % Value changed function: FreqStartEditField
        function FreqStartEditFieldValueChanged(app, event)
            value = app.FreqStartEditField.Value;


        end

        % Value changed function: FreqStopEditField
        function FreqStopEditFieldValueChanged(app, event)
            value = app.FreqStopEditField.Value;
            
        end

        % Value changed function: FreqpointEditField
        function FreqpointEditFieldValueChanged(app, event)
            value = app.FreqpointEditField.Value;
            
        end

        % Value changed function: IFBWKhzEditField
        function IFBWKhzEditFieldValueChanged(app, event)
            value = app.IFBWKhzEditField.Value;
            
        end

        % Value changed function: RFPowerdBEditField
        function RFPowerdBEditFieldValueChanged(app, event)
            value = app.RFPowerdBEditField.Value;
            
        end

        % Button pushed function: Measurex0zButton
        function Measurex0zButtonPushed(app, event)

            % Connexion avec libre vna
            host = '127.0.0.1';
            port = 19542;
            scpi = tcpclient(host, port, "Timeout", 30);

           
            % récupération des valeurs du GUI
            fstart = app.FreqStartEditField.Value * 1e6;
            fstop  = app.FreqStopEditField.Value * 1e6;
            points = app.FreqpointEditField.Value;
            IFBW   = app.IFBWKhzEditField.Value*1e3;
            RF_POWer = app.RFPowerdBEditField.Value;
            

            cmd1 = sprintf('VNA:FREQuency:STARt %d', round(fstart));
            cmd2 = sprintf('VNA:FREQuency:STOP %d', round(fstop));
            cmd3 = sprintf('VNA:ACQuisition:POINTS %d', round(points));
            cmd4 = sprintf('VNA:ACQuisition:IFBW %d', round(IFBW));
            cmd5 = sprintf('VNA:STIMulus:LVL %d', round(RF_POWer));
            disp (cmd1);
            disp (cmd2);
            disp (cmd3);
            disp (cmd4);
            disp (cmd5);
             
            
            writeline(scpi, string(cmd1));
            writeline(scpi, string(cmd2));
            writeline(scpi, string(cmd3));
            writeline(scpi, string(cmd4));
            writeline(scpi, string(cmd5));


            theta = [-180:app.sampling_theta:180-app.sampling_theta];
            
            % data2send='s';
            % write(app.s, data2send, "char");

            %Move motor to phi=0deg
            % angle = num2str(app.offset_angle);
            % angle = num2str(375);
            % data2send=strcat('M',angle ,'D')
            % write(app.s, data2send, "char");

            % sET First Polar
            % Write the port to the serial port
            data2send='!';
            write(app.s, data2send, "char");
            pause(app.delay_s);

            app.PortEditField.Value=0;
            p=1;
            fprintf('\n %d  :',p)

            for c = 0:1:2*length(theta)-1
                writeline(scpi,"VNA:ACQ:START");
                pause(0.5)

                % Lancement et lecture de S21
                % writeline(scpi, "VNA:TRACe:DATA? S21");
                % raw= readline(scpi);
                % 
                % 
                % raw = erase(raw, ["[", "]"]);
                % nums = str2double(split(raw, ","));

                writeline(scpi,"VNA:TRACE:DATA? S21");

                raw = "";

                while true
                    pause(0.05);

                    if scpi.NumBytesAvailable > 0
                        raw = raw + char(read(scpi, scpi.NumBytesAvailable));
                    end

                    nums = str2double(split(erase(raw, ["[", "]"]), ","));

                    if length(nums) >= points*3
                        break
                    end
                end

                data = reshape(nums, 3, []).';
                freqs = data(:,1);
                S21_Re = data(:,2);
                S21_Im = data(:,3);

                % Separation Etheta et Ephi
                if(mod(c,24) < 12)
                    Eth= S21_Re + 1i*S21_Im;
                    Etheta_x(p,mod(c,24)+floor(c/24)*12+1,:)= Eth;
                    plot(app.UIAxes,freqs,20*log10(abs(Eth)),'b')
                    legend(app.UIAxes,'Etheta');
                else
                    Eph= S21_Re + 1i*S21_Im;
                    Ephi_x(p,-12+mod(c,24)+floor(c/24)*12+1,:)= Eph;
                    plot(app.UIAxes2,freqs,20*log10(abs(Eph)),'r')
                    legend(app.UIAxes2,'Ephi');

                end

                % Write the port to the serial port
                data2send='$';
                write(app.s, data2send, "char");
                pause(app.delay_s);
                app.PortEditField.Value= app.PortEditField.Value+1;

                % fprintf('%d',mod(c,10))
                % if(app.ThetaSampDropDown.Value == '20')
                % 
                %     % Write the port to the serial port
                %     data2send='$';
                %     write(app.s, data2send, "char");
                %     % Pause for a moment to wait for a response
                % 
                %     pause(app.delay_s);
                %     app.PortEditField.Value= app.PortEditField.Value+1;
                % end

                app.Gauge.Value = 100*(c)/(length(theta)*2);


            end
            pause(app.delay_s);

            % app.LLamp.Color = 'green';
            app.Gauge.Value = 0;

           % add 360° theta values
           Etheta_x(:,length(theta)+1,:) = Etheta_x(:,1,:);
           Ephi_x(:,length(theta)+1,:)  =  Ephi_x(:,1,:) ;
           
           % Set as default measurement
           app.Ephi=Ephi_x;
           app.Etheta=-Etheta_x;  %Etheta is opposite direction // it was in the NF algo before

           app.sampling_phi = 0;
           app.sampling_theta = str2double(app.ThetaSampDropDown.Value);
           app.freqs = freqs;

            
        end

        % Button pushed function: XOZandYOZButton
        function XOZandYOZButtonPushed(app, event)
            % Connexion avec libre vna
            host = '127.0.0.1';
            port = 19542;
            scpi = tcpclient(host, port, "Timeout", 30);

            % récupération des valeurs du GUI
            fstart = app.FreqStartEditField.Value * 1e6;
            fstop  = app.FreqStopEditField.Value * 1e6;
            points = app.FreqpointEditField.Value;
            IFBW   = app.IFBWKhzEditField.Value*1e3;
            RF_POWer = app.RFPowerdBEditField.Value;

            cmd1 = sprintf('VNA:FREQuency:STARt %d', round(fstart));
            cmd2 = sprintf('VNA:FREQuency:STOP %d', round(fstop));
            cmd3 = sprintf('VNA:ACQuisition:POINTS %d', round(points));
            cmd4 = sprintf('VNA:ACQuisition:IFBW %d', round(IFBW));
            cmd5 = sprintf('VNA:STIMulus:LVL %d', round(RF_POWer));

            disp(cmd1); disp(cmd2); disp(cmd3); disp(cmd4); disp(cmd5);

            writeline(scpi, string(cmd1));
            writeline(scpi, string(cmd2));
            writeline(scpi, string(cmd3));
            writeline(scpi, string(cmd4));
            writeline(scpi, string(cmd5));

            theta = [-180:app.sampling_theta:180-app.sampling_theta];


            Etheta = zeros(2, length(theta), points);
            Ephi   = zeros(2, length(theta), points);

            data2send='s';
            write(app.s, data2send, "char");

            % XOZ
            % angle = num2str(330);
            % data2send=strcat('M',angle ,'D');
            % write(app.s, data2send, "char");

            data2send='!';
            write(app.s, data2send, "char");
            pause(app.delay_s);

            app.PortEditField.Value=0;
            p=1;

            for c = 0:1:2*length(theta)-1

                writeline(scpi,"VNA:ACQ:START");
                pause(0.5)

                writeline(scpi,"VNA:TRACE:DATA? S21");

                raw = "";

                while true
                    pause(0.05);
                    if scpi.NumBytesAvailable > 0
                        raw = raw + char(read(scpi, scpi.NumBytesAvailable));
                    end

                    nums = str2double(split(erase(raw, ["[", "]"]), ","));

                    if length(nums) >= points*3
                        break
                    end
                end

                data = reshape(nums, 3, []).';
                freqs = data(:,1);
                S21_Re = data(:,2);
                S21_Im = data(:,3);

                if(mod(c,24) < 12)
                    Eth= S21_Re + 1i*S21_Im;
                    Etheta(p,mod(c,24)+floor(c/24)*12+1,:)= Eth;
                    plot(app.UIAxes,freqs,20*log10(abs(Eth)),'b')
                    legend(app.UIAxes,'Etheta');
                else
                    Eph= S21_Re + 1i*S21_Im;
                    Ephi(p,-12+mod(c,24)+floor(c/24)*12+1,:)= Eph;
                    plot(app.UIAxes2,freqs,20*log10(abs(Eph)),'r')
                    legend(app.UIAxes2,'Ephi');
                end

                write(app.s, '$', "char");
                pause(app.delay_s);
                app.PortEditField.Value= app.PortEditField.Value+1;

                app.Gauge.Value = 100*(c)/(length(theta)*2);
            end

            pause(app.delay_s);
            app.Gauge.Value = 0;

            % YOZ
            angle = num2str(0);
            data2send=strcat('M',angle ,'D');
            write(app.s, data2send, "char");
            pause(2)
             
            data2send='!';
            write(app.s, data2send, "char");
            pause(app.delay_s);

            app.PortEditField.Value=0;
            p=2;

            % for c = 0:1:2*length(theta)-1
            % 
            %     writeline(scpi,"VNA:ACQ:START");
            %     pause(0.5)
            % 
            % 
            %     flush(scpi);
            %     writeline(scpi,"VNA:TRACE:DATA? S21");
            % 
            %     raw = "";
            %     while true
            %         pause(0.05);
            %         if scpi.NumBytesAvailable > 0
            %             raw = raw + char(read(scpi, scpi.NumBytesAvailable));
            %         end
            % 
            %         nums = str2double(split(erase(raw, ["[", "]"]), ","));
            % 
            %         if length(nums) >= points*3
            %             break
            %         end
            %     end

            for c = 0:1:2*length(theta)-1


                flush(scpi);


                writeline(scpi,"VNA:ACQ:START");

                pause(0.8)


                writeline(scpi,"VNA:TRACE:DATA? S21");

                raw = "";

                while true
                    pause(0.05);

                    if scpi.NumBytesAvailable > 0
                        raw = raw + char(read(scpi, scpi.NumBytesAvailable));
                    end

                    nums = str2double(split(erase(raw, ["[", "]"]), ","));

                    if length(nums) >= points*3
                        break
                    end
                end


                data = reshape(nums, 3, []).';
                freqs = data(:,1);
                S21_Rey = data(:,2);
                S21_Imy = data(:,3);

                if(mod(c,24) < 12)
                    Ethy= S21_Rey + 1i*S21_Imy;
                    Etheta(p,mod(c,24)+floor(c/24)*12+1,:)= Ethy;
                    plot(app.UIAxes,freqs,20*log10(abs(Ethy)),'b')
                    legend(app.UIAxes,'Etheta');
                else
                    Ephy= S21_Rey + 1i*S21_Imy;
                    Ephi(p,-12+mod(c,24)+floor(c/24)*12+1,:)= Ephy;
                    plot(app.UIAxes2,freqs,20*log10(abs(Ephy)),'r')
                    legend(app.UIAxes2,'Ephi');
                end

                write(app.s, '$', "char");
                pause(app.delay_s);
                app.PortEditField.Value= app.PortEditField.Value+1;
            end
               
              angle = num2str(90);
            data2send=strcat('M',angle ,'D');
            write(app.s, data2send, "char");
            pause(2)


            Etheta(:,length(theta)+1,:) = Etheta(:,1,:);
            Ephi(:,length(theta)+1,:)   = Ephi(:,1,:);

            disp(mean(abs(Etheta(1,:,:))))
            disp(mean(abs(Etheta(2,:,:))))

            app.Etheta = -Etheta;
            app.Ephi   = Ephi;

            app.sampling_phi = 0;
            app.sampling_theta = str2double(app.ThetaSampDropDown.Value);
            app.freqs = freqs;
        end

        % Value changed function: FreqEditField
        function FreqEditFieldValueChanged(app, event)
            % freq_diag = app.freqs(app.freq_id);
            % app.FreqEditField.Value = freq_diag;
            % f = app.FreqEditField.Value;
            % [d, ix] = min(abs(app.freqs - f));
            % app.freq_id = ix;
       % Value changed function: FreqEditField

           freq  = app.FreqEditField.Value;
           fmin  = app.FreqStartEditField.Value;
           fmax  = app.FreqStopEditField.Value;
           fpoint = app.FreqpointEditField.Value;
    
           id = round((freq - fmin) * fpoint / (fmax - fmin));
           id = max(1, min(id, fpoint));
           app.freq_idEditField.Value = id;
           app.freq_id = id;

            
        end

        % Value changed function: freq_idEditField
        function freq_idEditFieldValueChanged(app, event)
            % id = round(app.freq_idEditField.Value);
            % id = max(1, min(id, length(app.freqs)));
            % 
            % app.freq_id = id;
            app.freq_idEditField.Value = app.freq_id;
        end

        % Value changed function: MinEditField
        function MinEditFieldValueChanged(app, event)
           app.min_amp  = app.MinEditField.Value;
            
        end

        % Value changed function: MaxEditField
        function MaxEditFieldValueChanged(app, event)
            app.max_amp = app.MaxEditField.Value;
            
        end

        % Button pushed function: PolarPatternButton
        function PolarPatternButtonPushed(app, event)

            Etot = sqrt(abs(app.Etheta.^2) + abs(app.Ephi.^2));

            theta = [-180:app.sampling_theta:180];

            disp(app.freq_id);

            if (app.PolarDropDown.Value == "Eth/Eph")

                figure(1)

                % XOZ 
                p = 1;
                subplot(1,2,1, polaraxes)

                polarplot(deg2rad(theta),20*log10(abs(app.Etheta(p,:,app.freq_id))), 'g', 'DisplayName','Etheta')
                hold on

                rlim([app.min_amp app.max_amp])

                polarplot(deg2rad(theta),20*log10(abs(app.Ephi(p,:,app.freq_id))), 'b', 'DisplayName','Ephi')
                polarplot(deg2rad(theta),20*log10(abs(Etot(p,:,app.freq_id))), 'r', 'DisplayName','Etot')

                legend
                ax = gca;
                ax.ThetaZeroLocation = 'top';
                ax.ThetaDir = 'clockwise';

                title('XOZ')
                hold off


                % YOZ
                p = 2;
                subplot(1,2,2, polaraxes)

                polarplot(deg2rad(theta),20*log10(abs(app.Etheta(p,:,app.freq_id))), 'g', 'DisplayName','Etheta')
                hold on

                rlim([app.min_amp app.max_amp])

                polarplot(deg2rad(theta),20*log10(abs(app.Ephi(p,:,app.freq_id))), 'b', 'DisplayName','Ephi')
                polarplot(deg2rad(theta),20*log10(abs(Etot(p,:,app.freq_id))), 'r', 'DisplayName','Etot')

                legend
                ax = gca;
                ax.ThetaZeroLocation = 'top';
                ax.ThetaDir = 'clockwise';

                title('YOZ')
                hold off


            elseif (app.PolarDropDown.Value == "Elhcp/Erhcp")

                ELHCP = (app.Etheta + 1i*app.Ephi)/sqrt(2);
                ERHCP = (app.Etheta - 1i*app.Ephi)/sqrt(2);

                figure(1)

                % XOZ 
                p = 1;
                subplot(1,2,1, polaraxes)

                polarplot(deg2rad(theta),20*log10(abs(ELHCP(p,:,app.freq_id))), 'g', 'DisplayName','Elhcp')
                hold on

                rlim([app.min_amp app.max_amp])

                polarplot(deg2rad(theta),20*log10(abs(ERHCP(p,:,app.freq_id))), 'b', 'DisplayName','Erhcp')
                polarplot(deg2rad(theta),20*log10(abs(Etot(p,:,app.freq_id))), 'r', 'DisplayName','Etot')

                legend
                ax = gca;
                ax.ThetaZeroLocation = 'top';
                ax.ThetaDir = 'clockwise';

                title('XOZ')
                hold off


                %  YOZ 
                p = 2;
                subplot(1,2,2, polaraxes)

                polarplot(deg2rad(theta),20*log10(abs(ELHCP(p,:,app.freq_id))), 'g', 'DisplayName','Elhcp')
                hold on

                rlim([app.min_amp app.max_amp])

                polarplot(deg2rad(theta),20*log10(abs(ERHCP(p,:,app.freq_id))), 'b', 'DisplayName','Erhcp')
                polarplot(deg2rad(theta),20*log10(abs(Etot(p,:,app.freq_id))), 'r', 'DisplayName','Etot')

                legend
                ax = gca;
                ax.ThetaZeroLocation = 'top';
                ax.ThetaDir = 'clockwise';

                title('YOZ')
                hold off

            end


        end

        % Button pushed function: PolpatXOZButton
        function PolpatXOZButtonPushed(app, event)
          Etot =  sqrt(abs(app.Etheta.^2) + abs(app.Ephi.^2));

            % Etot2 = sqrt(abs(app.Ethetay.^2) + abs(app.Ephiy.^2));


            theta = [-180:app.sampling_theta:180];
             
            disp(app.freq_id);
            % use the phi value
            p=app.PhiDropDown.ValueIndex;

            if (app.PolarDropDown.Value == "Eth/Eph")

                figure(1)

                pax = polaraxes;   % First, Declare A 'polaraxes' Object & Return Its Handle

                %polarplot(deg2rad(port_list),20*log10(abs(Etheta(:,freq_id))))
                polarplot(deg2rad(theta),20*log10(abs(app.Etheta(p,:,app.freq_id))), 'g', 'DisplayName','Etheta')

                hold on

                rlim([app.min_amp app.max_amp])
                polarplot(deg2rad(theta),20*log10(abs(app.Ephi(p,:,app.freq_id))), 'b', 'DisplayName','Ephi')
                polarplot(deg2rad(theta),20*log10(abs(Etot(p,:,app.freq_id))), 'r', 'DisplayName','Etot')

                legend
                polaraxes(pax)
                pax.ThetaZeroLocation = 'top';                                  % Change Angle Origin
                pax.ThetaDir = 'clockwise';

                hold off

                title('Ephi vs Etheta and Gain')
                clims = [app.min_amp app.max_amp];


            elseif ((app.PolarDropDown.Value == "Elhcp/Erhcp"))

                ELHCP = (app.Etheta + 1i* app.Ephi)/sqrt(2);
                ERHCP = (app.Etheta - 1i* app.Ephi)/sqrt(2);

                figure(1)

                pax = polaraxes;   % First, Declare A 'polaraxes' Object & Return Its Handle
                subplot(1,2,1, polaraxes)
                polarplot(deg2rad(theta),20*log10(abs(ELHCP(p,:,app.freq_id))), 'g', 'DisplayName','Elhcp')

                hold on

                rlim([app.min_amp app.max_amp])
                polarplot(deg2rad(theta),20*log10(abs(ERHCP(p,:,app.freq_id))), 'b', 'DisplayName','Erhcp')
                polarplot(deg2rad(theta),20*log10(abs(Etot(p,:,app.freq_id))), 'r', 'DisplayName','Etot')

                legend
                polaraxes(pax)
                pax.ThetaZeroLocation = 'top';                                  % Change Angle Origin
                pax.ThetaDir = 'clockwise';

                hold off

                title(' Ephi vs Etheta and Gain')
                clims = [app.min_amp app.max_amp];


            end
        end

        % Button pushed function: AutoscaleButton
        function AutoscaleButtonPushed(app, event)
            Etot =  sqrt(abs(app.Etheta.^2) + abs(app.Ephi.^2));
            app.max_amp =  ceil(max(20*log10(abs(Etot)),[],"all"));
            app.MaxEditField.Value = app.max_amp;
            app.min_amp = floor(min(20*log10(abs(Etot)),[],"all"))
            app.MinEditField.Value = app.min_amp;
        end

        % Callback function: not associated with a component
        function Autoscale2ButtonPushed(app, event)
                        Etot =  sqrt(abs(app.Etheta.^2) + abs(app.Ephi.^2));
            app.max_amp =  ceil(max(20*log10(abs(Etot)),[],"all"));
            app.MaxEditField.Value = app.max_amp;
            app.min_amp = floor(min(20*log10(abs(Etot)),[],"all"))
            app.MinEditField.Value = app.min_amp;
        end

        % Button pushed function: ThetavsFreqButton
        function ThetavsFreqButtonPushed(app, event)

            host = '127.0.0.1';
            port = 19542;
            scpi = tcpclient(host, port, "Timeout", 30);

            % Measure first Theta polarization

            % Lancement et lecture de S21
            writeline(scpi, "VNA:TRACe:DATA? S21");
            raw= readline(scpi);
            pause(0.5);

            % Parse: Nettoyage des symboles '[]'et conversion en vecteur les données de S21
            raw = erase(raw, ["[", "]"]);
            nums = str2double(split(raw, ","));

            % Transformation du vecteur nums en matrice [freq, Re, Im]
            data = reshape(nums, 3, []).';
            freqs = data(:,1);


            theta = [-180:app.sampling_theta:180];

            clims = [app.min_amp app.max_amp];
            p=app.PhiDropDown.ValueIndex;

            if (app.PolarDropDown.Value == "Eth/Eph")

                figure(1)

                subplot(2,2,1);

                imagesc(freqs,theta,20*log10(abs(squeeze(app.Etheta(p,:,:)))),clims)
                xlabel('frequency (GHz)'); ylabel(' theta angle'); grid on
                title(' Etheta dB  vs theta vs freq')
                colorbar

                subplot(2,2,2);
                imagesc(freqs,theta,20*log10(abs(squeeze(app.Ephi(p,:,:)))),clims)
                xlabel('frequency (GHz)'); ylabel(' theta angle'); grid on
                title(' Ephi dB  vs theta vs freq')
                colorbar


                subplot(2,2,3);
                imagesc(freqs,theta,angle(squeeze(app.Etheta(p,:,:))))
                xlabel('frequency (GHz)'); ylabel(' theta angle'); grid on
                title(' Phase Etheta  vs theta vs freq')
                colorbar

                subplot(2,2,4);
                imagesc(freqs,theta,angle(squeeze(app.Ephi(p,:,:))))
                xlabel('frequency (GHz)'); ylabel(' theta angle'); grid on
                title(' Phase Ephi  vs theta vs freq')
                colorbar

            elseif ((app.PolarDropDown.Value == "Elhcp/Erhcp"))

                Elhcp = (app.Etheta + 1i* app.Ephi)/2;
                Erhcp = (app.Etheta - 1i* app.Ephi)/2;

                figure(2)

                subplot(2,2,1);

                imagesc(freqs,theta,20*log10(abs(squeeze(Elhcp(p,:,:)))),clims)
                xlabel('frequency (GHz)'); ylabel(' theta angle'); grid on
                title(' Elhcp dB  vs theta vs freq')
                colorbar

                subplot(2,2,2);
                imagesc(freqs,theta,20*log10(abs(squeeze(Erhcp(p,:,:)))),clims)
                xlabel('frequency (GHz)'); ylabel(' theta angle'); grid on
                title(' E dBrhcp  vs theta vs freq')
                colorbar


                subplot(2,2,3);
                imagesc(freqs,theta,angle(squeeze(Elhcp(p,:,:))))
                xlabel('frequency (GHz)'); ylabel(' theta angle'); grid on
                title(' Phase Elhcp  vs theta vs freq')
                colorbar

                subplot(2,2,4);
                imagesc(freqs,theta,angle(squeeze(Erhcp(p,:,:))))
                xlabel('frequency (GHz)'); ylabel(' theta angle'); grid on
                title(' Phase Erhcp  vs theta vs freq')
                colorbar

            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Magnitude dB')
            xlabel(app.UIAxes, 'freqs')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Magnitude dB')
            xlabel(app.UIAxes2, 'freqs')
            zlabel(app.UIAxes2, 'Z')
            app.PhiSampDropDownLabel = uilabel(app.UIFigure);
            app.PhiSampDropDownLabel.Text = 'Phi Samp';
            app.PhiSampDropDown = uidropdown(app.UIFigure);
            app.PhiSampDropDown.Items = {'10', '20', '30', '45', '60', '90'};
            app.PhiSampDropDown.Value = '10';
            app.ThetaSampDropDownLabel = uilabel(app.UIFigure);
            app.ThetaSampDropDownLabel.Text = 'Theta Samp';
            app.ThetaSampDropDown = uidropdown(app.UIFigure);
            app.ThetaSampDropDown.Items = {'10', '20', '30'};
            app.ThetaSampDropDown.Value = '10';
            app.PhiangleEditFieldLabel = uilabel(app.UIFigure);
            app.PhiangleEditFieldLabel.Text = 'Phi angle';
            app.PhiangleEditField = uieditfield(app.UIFigure, 'numeric');
            app.PortMCUEditFieldLabel = uilabel(app.UIFigure);
            app.PortMCUEditFieldLabel.Text = 'Port MCU';
            app.PortMCUEditField = uieditfield(app.UIFigure, 'text');
            app.PortMCUEditField.Value = 'COM3';
            app.FreqEditFieldLabel = uilabel(app.UIFigure);
            app.FreqEditFieldLabel.Text = 'Freq';
            app.FreqEditField = uieditfield(app.UIFigure, 'numeric');
            app.FreqEditField.ValueChangedFcn = createCallbackFcn(app, @FreqEditFieldValueChanged, true);
            app.FreqEditField.Value = 1600;
            app.PlotPostprocessingLabel = uilabel(app.UIFigure);
            app.PlotPostprocessingLabel.Text = 'Plot & Post-processing';
            app.MinEditFieldLabel = uilabel(app.UIFigure);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinEditField.ValueChangedFcn = createCallbackFcn(app, @MinEditFieldValueChanged, true);
            app.MinEditField.Value = -60;
            app.AutoscaleButton = uibutton(app.UIFigure, 'push');
            app.AutoscaleButton.ButtonPushedFcn = createCallbackFcn(app, @AutoscaleButtonPushed, true);
            app.AutoscaleButton.Text = 'Autoscale';
            app.PolarDropDownLabel = uilabel(app.UIFigure);
            app.PolarDropDownLabel.Text = 'Polar';
            app.PolarDropDown = uidropdown(app.UIFigure);
            app.PolarDropDown.Items = {'Eth/Eph', 'Elhcp/Erhcp', 'Option 2', 'Option 3', 'Option 4'};
            app.PolarDropDown.Value = 'Eth/Eph';
            app.PhiDropDownLabel = uilabel(app.UIFigure);
            app.PhiDropDownLabel.Text = 'Phi';
            app.PhiDropDown = uidropdown(app.UIFigure);
            app.PhiDropDown.Items = {'0', '20', '40', '60', '80', '100', '120', '140', '160'};
            app.PhiDropDown.Value = '0';
            app.ThetaDropDownLabel = uilabel(app.UIFigure);
            app.ThetaDropDownLabel.Text = 'Theta';
            app.ThetaDropDown = uidropdown(app.UIFigure);
            app.ThetaDropDown.Items = {'-180', '-170', '-160', '-150', '-140', '-120', '-110', '-100', '-90', '-80', '-70', '-60', '-50', '-40', '-30', '-20', '-10', '0', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100', '110', '120', '130', '140', '150', '160', '170', '180', ''};
            app.ThetaDropDown.Value = '-180';
            app.FreqStartEditFieldLabel = uilabel(app.UIFigure);
            app.FreqStartEditFieldLabel.Text = 'Freq Start';
            app.FreqStartEditField = uieditfield(app.UIFigure, 'numeric');
            app.FreqStartEditField.ValueChangedFcn = createCallbackFcn(app, @FreqStartEditFieldValueChanged, true);
            app.FreqStartEditField.Value = 1000;
            app.FreqStopEditFieldLabel = uilabel(app.UIFigure);
            app.FreqStopEditFieldLabel.Text = 'Freq Stop';
            app.FreqStopEditField = uieditfield(app.UIFigure, 'numeric');
            app.FreqStopEditField.ValueChangedFcn = createCallbackFcn(app, @FreqStopEditFieldValueChanged, true);
            app.FreqStopEditField.Value = 2000;
            app.FreqpointEditFieldLabel = uilabel(app.UIFigure);
            app.FreqpointEditFieldLabel.Text = 'Freq point';
            app.FreqpointEditField = uieditfield(app.UIFigure, 'numeric');
            app.FreqpointEditField.ValueChangedFcn = createCallbackFcn(app, @FreqpointEditFieldValueChanged, true);
            app.FreqpointEditField.Value = 101;
            app.SparamDropDownLabel = uilabel(app.UIFigure);
            app.SparamDropDownLabel.Text = 'S param';
            app.SparamDropDown = uidropdown(app.UIFigure);
            app.SparamDropDown.Items = {'S11', 'S12', 'S21', 'S22'};
            app.SparamDropDown.Value = 'S12';
            app.PortSpinnerLabel = uilabel(app.UIFigure);
            app.PortSpinnerLabel.Text = 'Port';
            app.PortSpinner = uispinner(app.UIFigure);
            app.PortSpinner.Value = 1;
            app.PolDropDownLabel = uilabel(app.UIFigure);
            app.PolDropDownLabel.Text = 'Pol';
            app.PolDropDown = uidropdown(app.UIFigure);
            app.PolDropDown.Items = {'Etheta', 'Ephi'};
            app.PolDropDown.Value = 'Etheta';
            app.PortEditFieldLabel = uilabel(app.UIFigure);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.UIFigure, 'numeric');
            app.MoveButton = uibutton(app.UIFigure, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Text = 'Move';
            app.XOZandYOZButton = uibutton(app.UIFigure, 'push');
            app.XOZandYOZButton.ButtonPushedFcn = createCallbackFcn(app, @XOZandYOZButtonPushed, true);
            app.XOZandYOZButton.Text = 'XOZ and YOZ';
            app.SetasRefButton = uibutton(app.UIFigure, 'push');
            app.SetasRefButton.ButtonPushedFcn = createCallbackFcn(app, @SetasRefButtonPushed, true);
            app.SetasRefButton.Text = 'Set as Ref';
            app.MeasurePortButton = uibutton(app.UIFigure, 'push');
            app.MeasurePortButton.ButtonPushedFcn = createCallbackFcn(app, @MeasurePortButtonPushed, true);
            app.MeasurePortButton.Text = 'Measure Port';
            app.Measurex0zButton = uibutton(app.UIFigure, 'push');
            app.Measurex0zButton.ButtonPushedFcn = createCallbackFcn(app, @Measurex0zButtonPushed, true);
            app.Measurex0zButton.Text = 'Measure x0z';
            app.PolarPatternButton = uibutton(app.UIFigure, 'push');
            app.PolarPatternButton.ButtonPushedFcn = createCallbackFcn(app, @PolarPatternButtonPushed, true);
            app.PolarPatternButton.Text = 'Polar Pattern';
            app.ThetavsFreqButton = uibutton(app.UIFigure, 'push');
            app.ThetavsFreqButton.ButtonPushedFcn = createCallbackFcn(app, @ThetavsFreqButtonPushed, true);
            app.ThetavsFreqButton.Text = 'Theta vs Freq';
            app.MeasurementsLabel = uilabel(app.UIFigure);
            app.MeasurementsLabel.Text = 'Measurements';
            app.freq_idEditFieldLabel = uilabel(app.UIFigure);
            app.freq_idEditFieldLabel.Text = 'freq_id';
            app.freq_idEditField = uieditfield(app.UIFigure, 'numeric');
            app.freq_idEditField.ValueChangedFcn = createCallbackFcn(app, @freq_idEditFieldValueChanged, true);
            app.freq_idEditField.Value = 61;
            app.IFBWKhzLabel = uilabel(app.UIFigure);
            app.IFBWKhzLabel.Text = 'IFBW (Khz)';
            app.IFBWKhzEditField = uieditfield(app.UIFigure, 'numeric');
            app.IFBWKhzEditField.ValueChangedFcn = createCallbackFcn(app, @IFBWKhzEditFieldValueChanged, true);
            app.IFBWKhzEditField.Value = 1;
            app.MaxEditFieldLabel = uilabel(app.UIFigure);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxEditField.ValueChangedFcn = createCallbackFcn(app, @MaxEditFieldValueChanged, true);
            app.MaxEditField.Value = -20;
            app.RFPowerdBEditFieldLabel = uilabel(app.UIFigure);
            app.RFPowerdBEditFieldLabel.Text = 'RF Power (dB)';
            app.RFPowerdBEditField = uieditfield(app.UIFigure, 'numeric');
            app.RFPowerdBEditField.ValueChangedFcn = createCallbackFcn(app, @RFPowerdBEditFieldValueChanged, true);
            app.RFPowerdBEditField.Value = -10;
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.UIFigure, 'linear');
            app.PolpatXOZButton = uibutton(app.UIFigure, 'push');
            app.PolpatXOZButton.ButtonPushedFcn = createCallbackFcn(app, @PolpatXOZButtonPushed, true);
            app.PolpatXOZButton.Text = 'Pol pat XOZ';
            app.MesureS11vsfreqButton = uibutton(app.UIFigure, 'push');
            app.MesureS11vsfreqButton.ButtonPushedFcn = createCallbackFcn(app, @MesureS11vsfreqButtonPushed, true);
            app.MesureS11vsfreqButton.Text = 'Mesure S11 vs freq';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Orb_GUI_v05_librevna

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end